classdef CoinCounter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfaz                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OptionsPanel                 matlab.ui.container.Panel
        OpenImage                    matlab.ui.control.Button
        CountButton                  matlab.ui.control.StateButton
        Exit                         matlab.ui.control.Button
        ResultsPanel                 matlab.ui.container.Panel
        NumberofcoinsEditFieldLabel  matlab.ui.control.Label
        num_coins                    matlab.ui.control.NumericEditField
        scoinsEditFieldLabel         matlab.ui.control.Label
        cant_50                      matlab.ui.control.NumericEditField
        scoinsLabel                  matlab.ui.control.Label
        cant_100                     matlab.ui.control.NumericEditField
        scoinsLabel_2                matlab.ui.control.Label
        cant_200                     matlab.ui.control.NumericEditField
        scoinsLabel_3                matlab.ui.control.Label
        cant_500                     matlab.ui.control.NumericEditField
        scoinsLabel_4                matlab.ui.control.Label
        cant_1000                    matlab.ui.control.NumericEditField
        CountPanel                   matlab.ui.container.Panel
        TOTALEditFieldLabel_2        matlab.ui.control.Label
        Total                        matlab.ui.control.EditField
        RightPanel                   matlab.ui.container.Panel
        grafica                      matlab.ui.control.UIAxes
        CoinCounterLabel             matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        file
        im % Description
        preprocess_im
        rmin
        rmax
        centers
        rads
        total
        num_50
        num_100
        num_200
        num_500
        num_1000
        NN
        num_total
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Button pushed function: OpenImage
        function OpenImageButtonPushed(app, event)
            global im
            [filename pathname] = uigetfile({'*.jpg'}, 'File Selector');
            figure(app.interfaz)
            fullpathname = strcat (pathname, filename);
            im = imread(fullpathname);
            app.im = im;
            imshow(im,'Parent',app.grafica);
            app.file = filename;
          
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            close(app.interfaz)
        end

        % Value changed function: CountButton
        function CountButtonValueChanged(app, event)
            value = app.CountButton.Value;
            if value == 1
                %Preprocesamiento
                app.preprocess_im = appPreprocess(app.im);
                
                %Transformada de Hough
                app.rmin = 150; app.rmax = 350;
                [app.centers, app.rads] = appHough(app.im, app.preprocess_im, app.rmin, app.rmax);
    
                appviscircles(app.grafica, app.centers, app.rads,'EdgeColor','r','EnhanceVisibility',false);
                
                %Conteo y etiquetado
                
                %Radios de referencia
                rad_50 = [150 195]; rad_100 = [203 227]; rad_200 = [227.01 241.99];
                rad_500 = [242 260]; rad_1000 = [270 350];
                
                %Valores
                val_50 = 50; val_100 = 100; val_200 = 200;
                val_500 = 500; val_1000 = 1000;
                
                %Conteo
                app.num_50 = 0; app.num_100 = 0; app.num_200 = 0; app.num_500 = 0; app.num_1000 = 0; app.NN = 0;
                app.num_total = 0;
                
                for x = 1:numel(app.rads)
                    if( (app.rads(x) >= rad_50(1)) && (app.rads(x) <= rad_50(2)) )
                        app.num_50 = app.num_50 + 1;
                        app.num_total = app.num_total + 1;
                        
                        text(app.grafica, app.centers(x,1), app.centers(x,2), num2str(val_50), ...
                            'Color', 'b', ...
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 18, ...
                            'FontWeight', 'bold');
                        
                    elseif ( (app.rads(x) >= rad_100(1)) && (app.rads(x) <= rad_100(2)) )
                        app.num_100 = app.num_100 + 1;
                        app.num_total = app.num_total + 1;
                        
                        text(app.grafica, app.centers(x,1), app.centers(x,2), num2str(val_100), ...
                            'Color', 'b', ...
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 18, ...
                            'FontWeight', 'bold');
                        
                    elseif ( (app.rads(x) >= rad_200(1)) && (app.rads(x) <= rad_200(2)) )
                        app.num_200 = app.num_200 + 1;
                        app.num_total = app.num_total + 1;
                        
                        text(app.grafica, app.centers(x,1), app.centers(x,2), num2str(val_200), ...
                            'Color', 'b', ...
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 18, ...
                            'FontWeight', 'bold');
                        
                    elseif ( (app.rads(x) >= rad_500(1)) && (app.rads(x) <= rad_500(2)) )
                        app.num_500 = app.num_500 + 1;
                        app.num_total = app.num_total + 1;
                        
                        text(app.grafica, app.centers(x,1), app.centers(x,2), num2str(val_500), ...
                            'Color', 'b', ...
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 18, ...
                            'FontWeight', 'bold');
                        
                    elseif ( (app.rads(x) >= rad_1000(1)) && (app.rads(x) <= rad_1000(2)) )
                        app.num_1000 = app.num_1000 + 1;
                        app.num_total = app.num_total + 1;
                        
                        text(app.grafica, app.centers(x,1), app.centers(x,2), num2str(val_1000), ...
                            'Color', 'b', ...
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 18, ...
                            'FontWeight', 'bold');
                        
                    else
                        app.NN = app.NN+1;
                        app. num_total = app.num_total + 1;
                        
                        text(app.grafica, app.centers(x,1), app.centers(x,2),'NN', ...
                            'Color', 'b', ...
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 18, ...
                            'FontWeight', 'bold');
                    end
                end
                
                app.total =(val_50*app.num_50) + (val_100*app.num_100) + (val_200*app.num_200) + (val_500*app.num_500) + (val_1000*app.num_1000);
                
                app.num_coins.Value = app.num_total;
                app.cant_50.Value = app.num_50;
                app.cant_100.Value = app.num_100;
                app.cant_200.Value = app.num_200;
                app.cant_500.Value = app.num_500;
                app.cant_1000.Value = app.num_1000;
                app.Total.Value = strcat('$ ', num2str(app.total));
                app.CountButton.Value = 0;
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.interfaz.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {912, 912};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {358, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfaz = uifigure('Visible', 'off');
            app.interfaz.Name = 'MATLAB App';
            app.interfaz.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.interfaz.Tag = 'Interfaz';
            app.GridLayout = uigridlayout(app.interfaz);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OptionsPanel = uipanel(app.LeftPanel);
            app.OptionsPanel.Title = 'Options';
            app.OpenImage = uibutton(app.OptionsPanel, 'push');
            app.OpenImage.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImage.Text = 'Open Image';
            app.CountButton = uibutton(app.OptionsPanel, 'state');
            app.CountButton.ValueChangedFcn = createCallbackFcn(app, @CountButtonValueChanged, true);
            app.CountButton.Text = 'Count';
            app.Exit = uibutton(app.OptionsPanel, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.ResultsPanel = uipanel(app.LeftPanel);
            app.ResultsPanel.Title = 'Results';
            app.NumberofcoinsEditFieldLabel = uilabel(app.ResultsPanel);
            app.NumberofcoinsEditFieldLabel.Text = 'Number of coins';
            app.num_coins = uieditfield(app.ResultsPanel, 'numeric');
            app.num_coins.ValueDisplayFormat = '%.0f';
            app.num_coins.Editable = 'off';
            app.scoinsEditFieldLabel = uilabel(app.ResultsPanel);
            app.scoinsEditFieldLabel.Text = '$ 50''s coins';
            app.cant_50 = uieditfield(app.ResultsPanel, 'numeric');
            app.cant_50.ValueDisplayFormat = '%.0f';
            app.cant_50.Editable = 'off';
            app.scoinsLabel = uilabel(app.ResultsPanel);
            app.scoinsLabel.Text = '$ 100''s coins';
            app.cant_100 = uieditfield(app.ResultsPanel, 'numeric');
            app.cant_100.ValueDisplayFormat = '%.0f';
            app.cant_100.Editable = 'off';
            app.scoinsLabel_2 = uilabel(app.ResultsPanel);
            app.scoinsLabel_2.Text = '$ 200''s coins';
            app.cant_200 = uieditfield(app.ResultsPanel, 'numeric');
            app.cant_200.ValueDisplayFormat = '%.0f';
            app.cant_200.Editable = 'off';
            app.scoinsLabel_3 = uilabel(app.ResultsPanel);
            app.scoinsLabel_3.Text = '$ 500''s coins';
            app.cant_500 = uieditfield(app.ResultsPanel, 'numeric');
            app.cant_500.ValueDisplayFormat = '%.0f';
            app.cant_500.Editable = 'off';
            app.scoinsLabel_4 = uilabel(app.ResultsPanel);
            app.scoinsLabel_4.Text = '$ 1000''s coins';
            app.cant_1000 = uieditfield(app.ResultsPanel, 'numeric');
            app.cant_1000.ValueDisplayFormat = '%.0f';
            app.cant_1000.Editable = 'off';
            app.CountPanel = uipanel(app.LeftPanel);
            app.CountPanel.Title = 'Count';
            app.TOTALEditFieldLabel_2 = uilabel(app.CountPanel);
            app.TOTALEditFieldLabel_2.Text = 'TOTAL';
            app.Total = uieditfield(app.CountPanel, 'text');
            app.Total.Editable = 'off';
            app.Total.Value = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.grafica = uiaxes(app.RightPanel);
            title(app.grafica, '')
            xlabel(app.grafica, '')
            ylabel(app.grafica, '')
            app.grafica.XTick = [];
            app.grafica.YTick = [];
            app.CoinCounterLabel = uilabel(app.RightPanel);
            app.CoinCounterLabel.Text = 'Coin Counter';
            app.ContextMenu = uicontextmenu(app.interfaz);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            % Assign app.ContextMenu
            app.grafica.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.interfaz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoinCounter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfaz)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfaz)
        end
    end
end